classdef Comp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Components                 matlab.ui.Figure
        Button                     matlab.ui.control.Button
        Resistor                   matlab.ui.control.CheckBox
        ResistanceEditFieldLabel   matlab.ui.control.Label
        ResistanceEditField        matlab.ui.control.NumericEditField
        CapacitorCheckBox          matlab.ui.control.CheckBox
        CoilCheckBox               matlab.ui.control.CheckBox
        CapacitanceEditFieldLabel  matlab.ui.control.Label
        CapacitanceEditField       matlab.ui.control.NumericEditField
        InductanceEditFieldLabel   matlab.ui.control.Label
        InductanceEditField        matlab.ui.control.NumericEditField
        Label                      matlab.ui.control.Label
        FLabel                     matlab.ui.control.Label
        HLabel                     matlab.ui.control.Label
    end


    properties (Access = public)
      I
      R
      C
    end

    methods (Access = public)
    
        function [R,I] = getVals(app)
            R = app.R;
            I = app.I;
            C = app.C;
        end
        
    end


    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.I=app.InductanceEditField.Value
            app.R=app.ResistanceEditField.Value
            app.C=app.CapacitanceEditField.Value
            uiresume;
        end

        % Value changed function: CapacitorCheckBox
        function CapacitorCheckBoxValueChanged(app, event)
            value = app.CapacitorCheckBox.Value;
            if value == 1
                app.CapacitanceEditField.Editable = 'on';
                app.CapacitanceEditField.Enable = 'on';
            end
            if value == 0
                app.CapacitanceEditField.Editable = 'off';
                app.CapacitanceEditField.Enable = 'off';
                app.CapacitanceEditField.Value = 0;
            end
            
        end

        % Value changed function: CoilCheckBox
        function CoilCheckBoxValueChanged(app, event)
            value = app.CoilCheckBox.Value;
            if value == 1
                app.InductanceEditField.Editable = 'on';
                app.InductanceEditField.Enable = 'on';
            end
            if value == 0
                app.InductanceEditField.Editable = 'off';
                app.InductanceEditField.Enable = 'off';
                app.InductanceEditField.Value = 0;
            end
        end

        % Value changed function: ResistanceEditField
        function ResistanceEditFieldValueChanged(app, event)
            value = app.ResistanceEditField.Value;
            
        end

        % Value changed function: Resistor
        function ResistorValueChanged(app, event)
            value = app.Resistor.Value;
            if value == 1
                app.ResistanceEditField.Editable = 'on';
                app.ResistanceEditField.Enable = 'on';
            end
            if value == 0
                app.ResistanceEditField.Editable = 'off';
                app.ResistanceEditField.Enable = 'off';
                app.ResistanceEditField.Value = 0;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Components = uifigure;
            app.Components.Name = 'UI Figure';
            app.Button = uibutton(app.Components, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Resistor = uicheckbox(app.Components);
            app.Resistor.ValueChangedFcn = createCallbackFcn(app, @ResistorValueChanged, true);
            app.Resistor.Text = 'Resistor';
            app.ResistanceEditFieldLabel = uilabel(app.Components);
            app.ResistanceEditFieldLabel.Text = 'Resistance';
            app.ResistanceEditField = uieditfield(app.Components, 'numeric');
            app.ResistanceEditField.ValueChangedFcn = createCallbackFcn(app, @ResistanceEditFieldValueChanged, true);
            app.ResistanceEditField.Editable = 'off';
            app.ResistanceEditField.Enable = 'off';
            app.CapacitorCheckBox = uicheckbox(app.Components);
            app.CapacitorCheckBox.ValueChangedFcn = createCallbackFcn(app, @CapacitorCheckBoxValueChanged, true);
            app.CapacitorCheckBox.Text = 'Capacitor';
            app.CoilCheckBox = uicheckbox(app.Components);
            app.CoilCheckBox.ValueChangedFcn = createCallbackFcn(app, @CoilCheckBoxValueChanged, true);
            app.CoilCheckBox.Text = 'Coil';
            app.CapacitanceEditFieldLabel = uilabel(app.Components);
            app.CapacitanceEditFieldLabel.Text = 'Capacitance';
            app.CapacitanceEditField = uieditfield(app.Components, 'numeric');
            app.CapacitanceEditField.Editable = 'off';
            app.CapacitanceEditField.Enable = 'off';
            app.InductanceEditFieldLabel = uilabel(app.Components);
            app.InductanceEditFieldLabel.Text = 'Inductance';
            app.InductanceEditField = uieditfield(app.Components, 'numeric');
            app.InductanceEditField.Editable = 'off';
            app.InductanceEditField.Enable = 'off';
            app.Label = uilabel(app.Components);
            app.Label.Text = 'Ω';
            app.FLabel = uilabel(app.Components);
            app.FLabel.Text = 'F';
            app.HLabel = uilabel(app.Components);
            app.HLabel.Text = 'H';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Comp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Components)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Components)
        end
    end
end